classdef BackEndThree < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        loop_checkTab                matlab.ui.container.Tab
        UIAxes                       matlab.ui.control.UIAxes
        file_pathEditFieldLabel      matlab.ui.control.Label
        file_pathEditField           matlab.ui.control.EditField
        submap_indexSpinnerLabel     matlab.ui.control.Label
        submap_indexSpinner          matlab.ui.control.Spinner
        loop_resultTextAreaLabel     matlab.ui.control.Label
        loop_resultTextArea          matlab.ui.control.TextArea
        Lamp                         matlab.ui.control.Lamp
        radon_matchTab               matlab.ui.container.Tab
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes3                      matlab.ui.control.UIAxes
        submap_indexSpinner_2Label   matlab.ui.control.Label
        submap_indexSpinner_2        matlab.ui.control.Spinner
        point_indexEditFieldLabel    matlab.ui.control.Label
        point_indexEditField         matlab.ui.control.NumericEditField
        loop_resultTextArea_2Label   matlab.ui.control.Label
        loop_resultTextArea_2        matlab.ui.control.TextArea
        BAB_resultTab                matlab.ui.container.Tab
        UIAxes4                      matlab.ui.control.UIAxes
        submap_indexSpinner_3Label   matlab.ui.control.Label
        submap_indexSpinner_3        matlab.ui.control.Spinner
        point_indexEditField_2Label  matlab.ui.control.Label
        point_indexEditField_2       matlab.ui.control.NumericEditField
        BAB_resultTextAreaLabel      matlab.ui.control.Label
        BAB_resultTextArea           matlab.ui.control.TextArea
        updateButton                 matlab.ui.control.StateButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: file_pathEditField
        function file_pathEditFieldValueChanged(app, event)
            global path T_estimate T_back radon_pos loop loop_close_tmp loop_pos label_pose
            value = app.file_pathEditField.Value;
            app.Lamp.Color=[0.8 0.8 0.8];
            if(isempty(value))
                path = '/home/yaoshw/Downloads';
            else
                path = value;
            end
            addpath('/home/yaoshw/matlabcode/Cartographer/FrontEnd');
            addpath('/home/yaoshw/matlabcode/Cartographer/BackEnd');
            [~, ~, ~, T_estimate] = ShowFrontPose(path);
            [~, T_back] = ShowBackPose(path);
            close all;
            fileList = dir([path '/radon/node*.txt']);
            fileNames={fileList.name};
            radon_pos=[];
            for i=1:length(fileNames)
                str = fileNames{i};
                s = find(fileNames{i}=='e');
                e = find(fileNames{i}=='.');
                radon_pos = [radon_pos;str2num(str(s+1:e-1))];
            end
            radon_pos = sort(radon_pos);
            
            loop = importdata([path '/loop_close.txt']);
            loop_close_tmp = importdata([path '/loop_close_tmp.txt']);
            loop_close_tmp = loop_close_tmp(loop_close_tmp(:,10)==1,1:2);
            
            keyframe = importdata([path '/can_be_frame.txt']);
            keyframe = keyframe(:,4);
            key_pos = find(keyframe==1);
            no_key_pos = find(keyframe==0);
            loop_pos = intersect(key_pos,1:20:length(keyframe));
            label_pose = importdata([path '/pose info.txt']);
            app.Lamp.Color=[0 1 0];
        end

        % Callback function
        function submap_indexEditFieldValueChanged(app, event)
            value = app.submap_indexEditField.Value;
        end

        % Value changed function: submap_indexSpinner
        function submap_indexSpinnerValueChanged(app, event)
            global path T_estimate T_back radon_pos loop loop_close_tmp loop_pos submap_radon_index i submap_poseT submap_poseR submap_local_point
            cla(app.UIAxes,"reset")
            value = app.submap_indexSpinner.Value;
            i = value;
            if i<1
                i=1;
            end
            if i>length(radon_pos)
                i=length(radon_pos);
            end
            if i==1
                submap_range = 1:radon_pos(i+1);
                submap_radon_index = radon_pos(i)+1;
            elseif i==length(radon_pos)
                submap_range = radon_pos(i-1):length(T_back);
                submap_radon_index = radon_pos(i)+1;
            else
                submap_range = radon_pos(i-1):radon_pos(i+1);
                submap_radon_index = radon_pos(i)+1;
            end
            submap_loop = loop(loop(:,2)==(i-1),1)+1;
            %     submap_can = intersect(loop_pos,submap_range);
            submap_can = loop_close_tmp(loop_close_tmp(:,2)==(i-1),1)+1;
            hold(app.UIAxes,"on");
            plt = plot(app.UIAxes,T_estimate(:,1),T_estimate(:,2),'k-');
            plt.Color(4) = 0.2;
            %     plot(T_back(:,1),T_back(:,2),'k--');
            plt = plot(app.UIAxes,T_estimate(loop_pos,1),T_estimate(loop_pos,2),'kx');
            plt.Color(4) = 0.2;
            no_loop_key_scan = setdiff(submap_can, submap_loop);
            for j=1:length(no_loop_key_scan)
                plt = text(app.UIAxes,T_estimate(no_loop_key_scan(j),1),T_estimate(no_loop_key_scan(j),2),num2str(no_loop_key_scan(j)-1));
                %                 plt.Color(4) = 0.2;
            end
            %     plot(T_back(loop_pos,1),T_back(loop_pos,2),'kx');
            plot(app.UIAxes,T_estimate(submap_range,1),T_estimate(submap_range,2),'r.');
            %     plot(T_back(submap_range,1),T_back(submap_range,2),'g.');
            plot(app.UIAxes,T_estimate(submap_radon_index,1),T_estimate(submap_radon_index,2),'b*','markersize',10);
            %     plot(T_back(submap_radon_index,1),T_back(submap_radon_index,2),'g*');
            plot(app.UIAxes,T_estimate(submap_can,1),T_estimate(submap_can,2),'rx');
            %     plot(T_back(submap_can,1),T_back(submap_can,2),'gx');
            plot(app.UIAxes,T_estimate(submap_loop,1),T_estimate(submap_loop,2),'gx');
            %     plot(T_back(submap_loop,1),T_back(submap_loop,2),'gx');
            title(app.UIAxes,['submap ' num2str(i-1)]);
            no_loop_key_scan_str=['no_loop_key_scan:' newline];
            
            if(isempty(no_loop_key_scan))
                no_loop_key_scan_str=['no_loop_key_scan: none' newline];
            else
                for m=1:length(no_loop_key_scan)
                    no_loop_key_scan_str=[no_loop_key_scan_str num2str(no_loop_key_scan(m)-1) newline];
                end
            end
            loop_key_scan_str=['loop_key_scan:' newline];
            if(isempty(submap_loop))
                loop_key_scan_str=['loop_key_scan: none' newline];
            else
                for m=1:length(submap_loop)
                    loop_key_scan_str=[loop_key_scan_str num2str(submap_loop(m)-1) newline];
                end
            end
            loop_result_str = ['submap_index: ' num2str(i-1) newline 'submap_radon_index: ' num2str(submap_radon_index-1) newline no_loop_key_scan_str newline loop_key_scan_str];
            app.loop_resultTextArea.Value = loop_result_str;
            app.loop_resultTextArea_2.Value = loop_result_str;
            app.submap_indexSpinner_2.Value = i;
            app.submap_indexSpinner_3.Value = i;
            submap_pose = importdata([path '/submap/submap_pose' num2str(i-1) '.txt']);
            submap_poseT = submap_pose(2,1:3);
            submap_poseR = quat2rotm(submap_pose(2,4:7));
            submap_local_point = importdata([path '/precomputationgrid/submap_' num2str(i-1) '_depth_0.txt']);
        end

        % Value changed function: point_indexEditField
        function point_indexEditFieldValueChanged(app, event)
            global submap_radon_index path point_index submap_poseT submap_poseR submap_local_point label_pose
            addpath('/home/yaoshw/matlabcode/Cartographer/RadonTransform');
            value = app.point_indexEditField.Value;
            cla(app.UIAxes2,"reset")
            cla(app.UIAxes3,"reset")
            point_index = value;
            app.point_indexEditField_2.Value = point_index;
            resolution = 0.02;
            submap_point = submap_local_point;
            submap_point(submap_point(:,4)<0.51,:) = [];
            point_s = importdata([path '/points/pcd_' num2str(submap_radon_index) '.txt']);
            submap_radon = RadonTransform(point_s,1,3);
            point_s=[point_s;[0 0 0];[0 0.2 0];[0 0.4 0]];
            
            point = importdata([path '/points/pcd_' num2str(point_index) '.txt']);
            point_radon = RadonTransform(point,10,3);
            point = [point;[0 0 0];[0 0.2 0];[0 0.4 0]];
            point_poseT = label_pose(point_index+1,1:3);
            point_poseR = quat2rotm(label_pose(point_index+1,4:7));
            
            label_radon_poseT = label_pose(submap_radon_index+1,1:3);
            label_radon_poseR = quat2rotm(label_pose(submap_radon_index+1,4:7));
            related_poseR = submap_poseR\point_poseR;
            related_poseT = submap_poseR\point_poseT' - submap_poseR\submap_poseT';
            scan_in_loop = related_poseR*point' + related_poseT;
            
            eul_ang = fliplr(rotm2eul(related_poseR,'ZYX'));
            label_radon_related_poseT = submap_poseR\label_radon_poseT' - submap_poseR\submap_poseT';
            label_radon_related_poseR = submap_poseR\label_radon_poseR;
            point_s_submap = label_radon_related_poseR*point_s' + label_radon_related_poseT;
            label_eul_ang = fliplr(rotm2eul(label_radon_related_poseR,'ZYX'));
            % initial_angle = eul_ang(3)
            initial_angle = eul_ang(3)-label_eul_ang(3);
            
            angles = -0.5:0.017:0.5;
            scores = RadonMatcher(submap_radon,point_radon,initial_angle,angles);
            selected_angle_pos = find(scores==max(scores));
            selected_angle = angles(selected_angle_pos(1));
            related_poseRradon = submap_poseR\rotz(selected_angle*180/pi)*point_poseR;
            scan_in_loop_rotation = related_poseRradon*point' + related_poseT;
            plot(app.UIAxes2,angles,scores);
            title(app.UIAxes2,num2str(point_index));
            
            submap_point = submap_poseR*submap_point(:,1:3)'+submap_poseT'./resolution;
            point_s_submap = submap_poseR*point_s_submap+submap_poseT';
            scan_in_loop = submap_poseR*scan_in_loop+submap_poseT';
            scan_in_loop_rotation = submap_poseR*scan_in_loop_rotation+submap_poseT';
            
            scatter3(app.UIAxes3,submap_point(1,:),submap_point(2,:),submap_point(3,:),3,[0.5 0.5 0.5],'filled','MarkerFaceAlpha',0.3);
            hold(app.UIAxes3,"on");
            scatter3(app.UIAxes3,point_s_submap(1,:)./resolution,point_s_submap(2,:)./resolution,point_s_submap(3,:)./resolution,8,'r','filled');
            scatter3(app.UIAxes3,scan_in_loop(1,:)./resolution,scan_in_loop(2,:)./resolution,scan_in_loop(3,:)./resolution,8,'g','filled');
            scatter3(app.UIAxes3,scan_in_loop_rotation(1,:)./resolution,scan_in_loop_rotation(2,:)./resolution,scan_in_loop_rotation(3,:)./resolution,8,'k','filled');
            legend(app.UIAxes3,'submap','submap radon','scan in submap','rotation scan');
            view(app.UIAxes3,0,90);
            title(app.UIAxes3,num2str(point_index));
        end

        % Value changed function: updateButton
        function updateButtonValueChanged(app, event)
            global i path point_index submap_poseT submap_poseR submap_local_point
            submap_index = i-1;
            resolution = 0.02;
            cla(app.UIAxes4,"reset")
            lowres = importdata([path '/BAB/LowResCan_' num2str(submap_index) '_node_' num2str(point_index) '.txt']);
            BAB_result_str=[num2str(point_index) ' low res max_score: '];
            if(~isempty(lowres))
                max_score = lowres(lowres(:,2)==max(lowres(:,2)),2);
                BAB_result_str=[BAB_result_str num2str(max_score(1)) newline];
                if(max_score>0.8)
                    submap_point = submap_local_point;
                    point = importdata([path '/points/pcd_' num2str(point_index) '.txt']);
                    point = [point;[0 0 0];[0 0.2 0];[0 0.4 0]];
                    BAB = importdata([path '/BAB/submap_' num2str(submap_index) '_node_' num2str(point_index) '.txt']);
                    BAB = sortrows(BAB,[4 5 6]);
                    high_level = find(BAB(:,1)==min(BAB(:,1)));
                    candidate_index = high_level(BAB(high_level,2)==max(BAB(high_level,2)));
                    pointT = BAB(candidate_index(1),4:6);
                    pointR = quat2rotm(BAB(candidate_index(1),7:10));
                    transf_point = pointR*point'+pointT';
                    BAB_result = BAB(candidate_index(1),1:2);
                    BAB_result_str=[BAB_result_str 'level: ' num2str(BAB_result(1)) newline];
                    BAB_result_str=[BAB_result_str 'BAB max score: ' num2str(BAB_result(2))];
                    submap_point(submap_point(:,4)<0.51,:) = [];
                    
                    submap_point = submap_poseR*submap_point(:,1:3)'+submap_poseT'./resolution;
                    transf_point = submap_poseR*transf_point+submap_poseT';
                    
                    scatter3(app.UIAxes4,submap_point(1,:),submap_point(2,:),submap_point(3,:),3,[0.5 0.5 0.5],'filled','MarkerFaceAlpha',0.3);
                    hold(app.UIAxes4,"on");
                    scatter3(app.UIAxes4,transf_point(1,:)./resolution,transf_point(2,:)./resolution,transf_point(3,:)./resolution,8,'g','filled');
                    legend(app.UIAxes4,'submap','scan in submap');
                    view(app.UIAxes4,0,90);
                    title(app.UIAxes4,num2str(point_index));
                end
            else
                BAB_result_str = [num2str(point_index) ' radon match no result!'];
            end
            app.BAB_resultTextArea.Value=BAB_result_str;
            app.updateButton.Value = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.loop_checkTab = uitab(app.TabGroup);
            app.loop_checkTab.Title = 'loop_check';
            app.UIAxes = uiaxes(app.loop_checkTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.file_pathEditFieldLabel = uilabel(app.loop_checkTab);
            app.file_pathEditFieldLabel.Text = 'file_path';
            app.file_pathEditField = uieditfield(app.loop_checkTab, 'text');
            app.file_pathEditField.ValueChangedFcn = createCallbackFcn(app, @file_pathEditFieldValueChanged, true);
            app.submap_indexSpinnerLabel = uilabel(app.loop_checkTab);
            app.submap_indexSpinnerLabel.Text = 'submap_index';
            app.submap_indexSpinner = uispinner(app.loop_checkTab);
            app.submap_indexSpinner.ValueChangedFcn = createCallbackFcn(app, @submap_indexSpinnerValueChanged, true);
            app.submap_indexSpinner.Value = 1;
            app.loop_resultTextAreaLabel = uilabel(app.loop_checkTab);
            app.loop_resultTextAreaLabel.Text = 'loop_result';
            app.loop_resultTextArea = uitextarea(app.loop_checkTab);
            app.loop_resultTextArea.Editable = 'off';
            app.Lamp = uilamp(app.loop_checkTab);
            app.radon_matchTab = uitab(app.TabGroup);
            app.radon_matchTab.Title = 'radon_match';
            app.UIAxes2 = uiaxes(app.radon_matchTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes3 = uiaxes(app.radon_matchTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.submap_indexSpinner_2Label = uilabel(app.radon_matchTab);
            app.submap_indexSpinner_2Label.Text = 'submap_index';
            app.submap_indexSpinner_2 = uispinner(app.radon_matchTab);
            app.submap_indexSpinner_2.Editable = 'off';
            app.submap_indexSpinner_2.Value = 1;
            app.point_indexEditFieldLabel = uilabel(app.radon_matchTab);
            app.point_indexEditFieldLabel.Text = 'point_index';
            app.point_indexEditField = uieditfield(app.radon_matchTab, 'numeric');
            app.point_indexEditField.ValueChangedFcn = createCallbackFcn(app, @point_indexEditFieldValueChanged, true);
            app.loop_resultTextArea_2Label = uilabel(app.radon_matchTab);
            app.loop_resultTextArea_2Label.Text = 'loop_result';
            app.loop_resultTextArea_2 = uitextarea(app.radon_matchTab);
            app.loop_resultTextArea_2.Editable = 'off';
            app.BAB_resultTab = uitab(app.TabGroup);
            app.BAB_resultTab.Title = 'BAB_result';
            app.UIAxes4 = uiaxes(app.BAB_resultTab);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            app.submap_indexSpinner_3Label = uilabel(app.BAB_resultTab);
            app.submap_indexSpinner_3Label.Text = 'submap_index';
            app.submap_indexSpinner_3 = uispinner(app.BAB_resultTab);
            app.submap_indexSpinner_3.Editable = 'off';
            app.submap_indexSpinner_3.Value = 1;
            app.point_indexEditField_2Label = uilabel(app.BAB_resultTab);
            app.point_indexEditField_2Label.Text = 'point_index';
            app.point_indexEditField_2 = uieditfield(app.BAB_resultTab, 'numeric');
            app.point_indexEditField_2.Editable = 'off';
            app.BAB_resultTextAreaLabel = uilabel(app.BAB_resultTab);
            app.BAB_resultTextAreaLabel.Text = 'BAB_result';
            app.BAB_resultTextArea = uitextarea(app.BAB_resultTab);
            app.BAB_resultTextArea.Editable = 'off';
            app.updateButton = uibutton(app.BAB_resultTab, 'state');
            app.updateButton.ValueChangedFcn = createCallbackFcn(app, @updateButtonValueChanged, true);
            app.updateButton.Text = 'update';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BackEndThree

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end